classdef hammer_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LatestacquisitionTab            matlab.ui.container.Tab
        ImpactWaveformAxes              matlab.ui.control.UIAxes
        ImpactPSDAxes                   matlab.ui.control.UIAxes
        PSDAxes                         matlab.ui.control.UIAxes
        CoheranceAxes                   matlab.ui.control.UIAxes
        FrequencyResponseAxes           matlab.ui.control.UIAxes
        ChannelSettingsPanel            matlab.ui.container.Panel
        ChannelnameEditFieldLabel       matlab.ui.control.Label
        ChannelnameEditField            matlab.ui.control.EditField
        DevicetouseDropDown             matlab.ui.control.DropDown
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        ACcouplingCheckBox              matlab.ui.control.CheckBox
        ChannelIDDropDown               matlab.ui.control.DropDown
        ExcitationcurrentAEditFieldLabel  matlab.ui.control.Label
        ExcitationcurrentAEditField     matlab.ui.control.NumericEditField
        InternalSensitivityEditFieldLabel  matlab.ui.control.Label
        InternalSensitivityEditField    matlab.ui.control.NumericEditField
        AccSensitivityVgEditFieldLabel  matlab.ui.control.Label
        AccSensitivityVgEditField       matlab.ui.control.NumericEditField
        AddChannelButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        ChannelsListBoxLabel            matlab.ui.control.Label
        ChannelsListBox                 matlab.ui.control.ListBox
        RemoveSelectedChannelsButton    matlab.ui.control.Button
        LoadcellImpactHammerCheckBox    matlab.ui.control.CheckBox
        ScanForDevicesButton            matlab.ui.control.Button
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        TriggerSettingsPanel            matlab.ui.container.Panel
        UseTriggerCheckBox              matlab.ui.control.CheckBox
        TriggersetpointVEditFieldLabel  matlab.ui.control.Label
        TriggersetpointVEditField       matlab.ui.control.NumericEditField
        TriggerWindowsSpinnerLabel      matlab.ui.control.Label
        TriggerWindowsSpinner           matlab.ui.control.Spinner
        TimeoutsEditFieldLabel          matlab.ui.control.Label
        TimeoutsEditField               matlab.ui.control.NumericEditField
        CollectionTimesEditFieldLabel   matlab.ui.control.Label
        CollectionTimesEditField        matlab.ui.control.NumericEditField
        SamplingFreqHzDropDownLabel     matlab.ui.control.Label
        SamplingFreqHzDropDown          matlab.ui.control.DropDown
        SystemmasskgEditFieldLabel      matlab.ui.control.Label
        SystemmasskgEditField           matlab.ui.control.NumericEditField
        ConfigurationDetailsPanel       matlab.ui.container.Panel
        TestarticleEditFieldLabel       matlab.ui.control.Label
        TestarticleEditField            matlab.ui.control.EditField
        TestEngineerEditFieldLabel      matlab.ui.control.Label
        TestEngineerEditField           matlab.ui.control.EditField
        AcquireDataButton               matlab.ui.control.StateButton
        LoadDataButton                  matlab.ui.control.Button
        AddDatatoCummulativeButton      matlab.ui.control.Button
        DampngConstantEditFieldLabel    matlab.ui.control.Label
        DampngConstantEditField         matlab.ui.control.NumericEditField
        CummulativeAcquisitionsTab      matlab.ui.container.Tab
        ChannelInfoPanel                matlab.ui.container.Panel
        ChannelListListBoxLabel         matlab.ui.control.Label
        ChannelListListBox              matlab.ui.control.ListBox
        ChannelDetailsTextAreaLabel     matlab.ui.control.Label
        ChannelDetailsTextArea          matlab.ui.control.TextArea
        ConfigurationInfoPanel          matlab.ui.container.Panel
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        ConfigurationdataTextAreaLabel  matlab.ui.control.Label
        ConfigurationdataTextArea       matlab.ui.control.TextArea
        DataInfoPanel                   matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        NumberofSamplesEditFieldLabel   matlab.ui.control.Label
        NumberofSamplesEditField        matlab.ui.control.NumericEditField
        NumberofTestsEditFieldLabel     matlab.ui.control.Label
        NumberofTestsEditField          matlab.ui.control.NumericEditField
        NumberofChannelsEditFieldLabel  matlab.ui.control.Label
        NumberofChannelsEditField       matlab.ui.control.NumericEditField
        ClearcummulativedataButton      matlab.ui.control.Button
        InteractwithDataButton          matlab.ui.control.Button
        LabViewModeCheckBox             matlab.ui.control.CheckBox
        PSDAxesCumm                     matlab.ui.control.UIAxes
        ImpactPSDAxesCumm               matlab.ui.control.UIAxes
        FrequencyResponseAxesCumm       matlab.ui.control.UIAxes
        CoheranceAxesCumm               matlab.ui.control.UIAxes
        AverageDampingConstEditFieldLabel  matlab.ui.control.Label
        AverageDampingConstEditField    matlab.ui.control.NumericEditField
        DebugButton                     matlab.ui.control.Button
        PlottimedomaindataButton        matlab.ui.control.Button
        PlotOptionsPanel                matlab.ui.container.Panel
        xmaxEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        AxisScalingButtonGroup          matlab.ui.container.ButtonGroup
        LoglogButton                    matlab.ui.control.RadioButton
        SemilogxButton                  matlab.ui.control.RadioButton
        SemilogyButton                  matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        ReplotButton                    matlab.ui.control.Button
        ImpactPeakMaxNLabel             matlab.ui.control.Label
        ImpactPeakMaxNEditField         matlab.ui.control.NumericEditField
        ImpactPeakMinNEditFieldLabel    matlab.ui.control.Label
        ImpactPeakMinNEditField         matlab.ui.control.NumericEditField
    end

    methods (Access = private)
        % Takes a Channel struct and uses relevant fields to contstruct an analog input channel for daq session s
        function populateDAQChannel(~, channel)
            global s;
            addAnalogInputChannel(s, channel.deviceName, channel.ID,  channel.measurementType)
            s.Channels(end).Name = channel.displayName;
            
            % Populate the new channel in 's' with fields from the 'channel' argument
            fields = fieldnames(channel.daqAttributes);
            for index = 1:length(fields)
                field = fields{index};
                if isfield(s.Channels(end), field)
                    currChannel = s.Channels(end);
                    currChannel.(field) = channel.daqAttributes.(field);
                    s.Channels(end) = currChannel;
                end
            end
        end
        
        % Sets up the DAQ session and tries to read from the DAQ if it connected properly
            % returns DAQ session 's'
        function s = configureDAQ(app)
            s = daq.createSession('ni');
            devices = daq.getDevices;
            % Give warning if it can't detect devices
            if isempty(devices)
                uialert(app.UIFigure, 'Warning, no DAQ devices found. Make sure USB is plugged in. If problems persist, restart MATLAB.', 'No devices found!')
                s = [];
                return;
            end
            
            % Populate UI options based on the available features of 's'
            measurementTypes = devices(1,2).Subsystems.MeasurementTypesAvailable';
            app.MeasurementTypeDropDown.Items = measurementTypes;

            app.DevicetouseDropDown.Items = cellstr(char(devices.ID));
            
            app.ChannelIDDropDown.Items = devices(1,2).Subsystems.ChannelNames;
            
            app.ScanForDevicesButton.Enable = 'off';
        end
        
        % Rescan for a connected DAQ device
            % returns res=true if the DAQ session is populated properly, returns res=false if not
        function res = checkDAQConfig(app, s)
            if isempty(s)
                res = false;
                uialert(app.UIFigure, 'Warning, no DAQ devices found. Make sure USB is plugged in. If problems persist, restart MATLAB.', 'No devices found!');
                app.AcquireDataButton.Value = 0;
            else
                res = true;
            end
        end
        
        % Make sure all of the configuration settings for acquisition are properly set up for data acquisition
            % returns res=true if config is valid, returns res=false if config needs to be changed
        function res = checkAcquisitionConfig(app, s)
            % Make sure there are initiated channels
            if isempty(s.Channels)
                res = false;
                uialert(app.UIFigure, 'Warning, no DAQ Channels set up. Make sure to add channels to start Data Acquisition')
                app.AcquireDataButton.Value = 0;
                return
            % Make sure there is a hammer/load-cell channel
            elseif ~sum(getHammerChannels(app))
                res = false;
                uialert(app.UIFigure, 'Warning, no load cell Channel set up. Make sure to add at least one channel corresponding to the load cell of the hammer', 'No Load Cell Channel!');
                app.AcquireDataButton.Value = 0;
                return
            % Make sure the collection time isn't zero
            elseif app.CollectionTimesEditField.Value == 0
                res = false;
                uialert(app.UIFigure, 'Collection time must be positive real number', 'Invalid Collection Time');
                app.AcquireDataButton.Value = 0;
                return
            % Make sure the system mass isn't zero
            elseif app.SystemmasskgEditField.Value == 0
                res = false;
                uialert(app.UIFigure, 'System mass must be a positive real number', 'Invalid System Mass');
                app.AcquireDataButton.Value = 0;
                return
            else
                res = true;
            end
        end
        
        % Handles data collection. Has impact detection and automatically stops itself once collection time is up.
        % Updates global variables 'data' and 'time' with new collected data 
        function collectData(app, src, event)
            global collectionComplete;
            global dataBuffer;
            global timeBuffer;
            global timeoutT0;
            global t0;
            global data;
            global time;
            
            % Gather collection time
            totalTime = app.CollectionTimesEditField.Value + app.TriggerWindowsSpinner.Value;
            
            disp('packet recieved')
            disp(size(event.TimeStamps))
            
            % Check for triggering. If t0==0, the trigger has yet to be set off
            if t0 == 0
                disp('Waiting for trigger event...')
                % Abort collection in the case of a trigger timeout
                if (toc(timeoutT0) > app.TimeoutsEditField.Value) && app.UseTriggerCheckBox.Value
                    collectionComplete = 2;
                    disp('TRIGGER TIME OUT')
                end
                
                % Check for a triggering piece of data in the current dataset
                if max(max(dataBuffer)) > app.TriggersetpointVEditField.Value
                    % Start to collect data, 
                    t0 = tic;
                    
                    % Move data from time and data buffers to the real time and data stores
                    % Ensure that the current dataset is unique and occurs strictly after the last data set
                    if max(timeBuffer) < min(event.TimeStamps)
                        data = [flipud(dataBuffer(timeBuffer > -1, :));event.Data];
                        time = [flipud(timeBuffer(timeBuffer > -1));event.TimeStamps];
                    
                    % if current data isn't unique, don't include it in the new data and time stores
                    else
                        data = [flipud(dataBuffer(timeBuffer > -1, :))];
                        time = [flipud(timeBuffer(timeBuffer > -1))];
                    end
                    
                    app.AcquireDataButton.Text = 'Acquiring Data...';
                
                % If there is no triggering piece of data in the current dataset: 
                else
                    shift = size(event.Data, 1);
                    % Ensure that incomming data is unique and occurs strictly after the last dataset
                    if event.TimeStamps(1) > timeBuffer(1)
                        % populate the data buffer, overwriting the earliest data that is in the way
                        dataBuffer = circshift(dataBuffer, shift);
                        dataBuffer(1:shift, :) = flipud(event.Data);
                        
                        % populate the time buffer, overwriting the earliest data that is in the way
                        time_shift = size(event.TimeStamps, 1);
                        timeBuffer = circshift(timeBuffer, time_shift);
                        timeBuffer(1:time_shift, :) = flipud(event.TimeStamps);
                    end
                end
            
            % if the trigger has been set off, but collection time hasn't ended yet
            elseif max(event.TimeStamps) - time(1) < totalTime
                % This is a really sloppy fix to an issue where concurrent runs
                % of the data acquisition script would cause duplicate packets of
                % data to be imported. The root cause was never identified 7/9/19
                if event.TimeStamps(1) > time(end)
                    data = [data; event.Data];
                    time = [time; event.TimeStamps];
                end
                
            % if the number of samples collected matches the number required of the collection time specified
            else
                collectionComplete = 1;
                disp('FINISHED')
            end
        end
        
        % Take raw hammer, accel and time data and process it
            % returns struct parsedData that contains each calculated metric
        function parsedData = parseData(app, hammerData, accelData, time, plotOn)
            % Sampling frequency
            Fs = 1/mean(diff(time));
            
            parsedData = struct();
            
            [parsedData.impactTs, parsedData.impactMags] = findImpact(app, hammerData, time, Fs, 0.005);
            
            [parsedData.impactPSDMags, parsedData.impactPSDFs] = calcSD(app, hammerData, Fs);
            
            [parsedData.freqRespMags, parsedData.freqRespFs] = calcFrequencyResponse(app, accelData, hammerData, Fs);
            
            [parsedData.PSDMags, parsedData.PSDFs] = calcSD(app, accelData, Fs);
            
            [parsedData.crossSD, ~] = calcSD(app, accelData, hammerData, Fs);
            
            estimateDampingCoef(app, accelData(:, 1:3), time, parsedData.impactTs, 0)
            
            if plotOn
                plotData(app, parsedData, 0)
            end
        end
        
        % Takes struct of parsed data and plots it
        function fig = plotData(app, parsedData, makeNewFig)
            global Channels
            fig = [];
            
            % populate legends for graphs
            channelLegend = cellfun(@(channel) channel.name, Channels, 'UniformOutput', 0);
            accelLegend = channelLegend(cellfun(@(channel) ~channel.isLoadCell, Channels));    
            
            % Set axes depending on user input and current UI state:
            % If user has specified to plot the graphs on a standalone figure
            if makeNewFig
                fig = figure;
                tab1 = uitab('Title','Impact PSD');
                impactPSDAxes = axes(tab1);
                
                tab2 = uitab('Title','Frequency Response');
                frequencyResponseAxes = axes(tab2);

                tab3 = uitab('Title','PSD');                
                PSDAxes = axes(tab3);
                
                
                tab4 = uitab('Title','Coherance');
                coheranceAxes = axes(tab4);
                
                
            % If the current tab of the UI is the 'Data Acquisition' tab
            elseif app.TabGroup.SelectedTab == app.TabGroup.Children(1)
                % Plot impact in time domain
                plot(app.ImpactWaveformAxes, parsedData.impactTs, parsedData.impactMags);
                t0 = parsedData.impactTs(0);
                t_end = parsedData.impactTs(end);
                peak = max(parsedData.impactMags);
                peak_max = app.ImpactPeakMaxNEditField.Value;
                peak_min = app.ImpactPeakMinNEditField.Value;
                
                if peak > peak_max
                    hold on
                    plot(app.ImpactWaveformAxes, [t0, t_end], [peak_max, peak_max], 'r--');
                    legend(app.ImpactWaveformAxes, {'Signal', 'User Defined Peak Max'});
                elseif peak < peak_min
                    hold on
                    plot(app.ImpactWaveformAxes, [t0, t_end], [peak_min, peak_min], 'r--');
                    legend(app.ImpactWaveformAxes, {'Signal', 'User Defined Peak Min'});
                end
                
                impactPSDAxes = app.ImpactPSDAxes; 
                frequencyResponseAxes = app.FrequencyResponseAxes;
                PSDAxes = app.PSDAxes;
                coheranceAxes = app.CoheranceAxes;
                
            % If the current tab of the UI is the 'Cummulative Data' tab
            else
                impactPSDAxes = app.ImpactPSDAxesCumm; 
                frequencyResponseAxes = app.FrequencyResponseAxesCumm;
                PSDAxes = app.PSDAxesCumm;
                coheranceAxes = app.CoheranceAxesCumm;
            end
            
            % Plot PSD for the impact (over all time)
            loglog(impactPSDAxes, parsedData.impactPSDFs, parsedData.impactPSDMags);
            
            % Plot frequency response of the accelerometers to get 1/mass
            loglog(frequencyResponseAxes, parsedData.freqRespFs, parsedData.freqRespMags);
            mass = app.SystemmasskgEditField.Value;
            hold(frequencyResponseAxes, 'on')
            % Plot known inverse mass line
            loglog(frequencyResponseAxes, [parsedData.freqRespFs(2), parsedData.freqRespFs(end)], [1/mass, 1/mass], 'r--');
            hold(frequencyResponseAxes, 'off')
            frequencyResponseLegend = accelLegend;
            frequencyResponseLegend{end+1} = '1/mass';
            legend(frequencyResponseAxes, frequencyResponseLegend)
            
            % Plot welch estimate of PSD
            loglog(PSDAxes, parsedData.PSDFs, parsedData.PSDMags);
            legend(PSDAxes, accelLegend);
            
            % Plot magnitude squared coherance between the hammer and accel channels
            coh = calcCoherance(app, parsedData.PSDMags, parsedData.impactPSDMags, parsedData.crossSD);
            semilogx(coheranceAxes, parsedData.PSDFs, coh);
            legend(coheranceAxes, accelLegend);
            
            title(impactPSDAxes, 'Averaged Impact PSDs')
            xlabel(impactPSDAxes, 'Frequency (Hz)')
            ylabel(impactPSDAxes, 'N^2 / Hz');
            title(frequencyResponseAxes, 'Averaged Frequency Response')
            xlabel(frequencyResponseAxes, 'Frequency (Hz)')
            ylabel(frequencyResponseAxes, '1/kg');
            title(PSDAxes, 'Averaged Accelerometer PSD')
            xlabel(PSDAxes, 'Frequency (Hz)')
            ylabel(PSDAxes, '(m/s^2)^2 / Hz');
            title(coheranceAxes, 'Averaged Coherance')
            xlabel(coheranceAxes, 'Frequency (Hz)')
            ylabel(coheranceAxes, 'Coherance (unitless)');
            
            
            if strcmp(app.xmaxEditField.Enable, 'off')
                togglePanel(app, app.PlotOptionsPanel, 'on')
                app.LoglogButton.Enable = 'on';
                app.SemilogxButton.Enable = 'on';
                app.SemilogyButton.Enable = 'on';
                app.LinearButton.Enable = 'on';
                app.xmaxEditField.Value = max(parsedData.PSDFs);
                app.xminEditField.Value = min(parsedData.PSDFs);
            else
                xlim(PSDAxes, [app.xminEditField.Value, app.xmaxEditField.Value])
                xlim(frequencyResponseAxes, [app.xminEditField.Value, app.xmaxEditField.Value])
                xlim(coheranceAxes, [app.xminEditField.Value, app.xmaxEditField.Value])
                ylim(PSDAxes, [-Inf, Inf])
                ylim(frequencyResponseAxes, [-Inf, Inf])
                ylim(coheranceAxes, [-Inf, Inf])
                
                if app.LoglogButton.Value
                    xscale = 'log';
                    yscale = 'log';
                elseif app.SemilogxButton.Value
                    xscale = 'log';
                    yscale = 'linear';
                elseif app.SemilogyButton.Value
                    xscale = 'linear';
                    yscale = 'log';
                elseif app.LinearButton.Value
                    xscale = 'linear';
                    yscale = 'linear';
                end
                set(impactPSDAxes,'XScale',xscale,'YScale',yscale)
                set(frequencyResponseAxes,'XScale',xscale,'YScale',yscale)
                set(PSDAxes,'XScale',xscale,'YScale',yscale)
            end
        end
        
        % Calculates the Spectral Density estimate for a signal
            % To calculate Auto-Spectral Density, provide arguments app and data
            % To calculate Cross-Spectral Density, provide arguments app, data1 and data2
            % returns PSD magnitudes and corresponding frequencies
        function [PSD,freqs] = calcSD(app, varargin)
            % Only one data input, therefore, calculate the PSD
            if length(varargin) == 2
                data1 = varargin{1};
                data2 = data1;
                Fs = varargin{2};
            
            % Two data inputs, therefore, calculate the Cross SD
            elseif length(varargin) == 3
                data1 = varargin{1};
                data2 = varargin{2};
                Fs = varargin{3}; 
            end
            
            n = length(data1);
            [PSD, freqs] = cpsd(data1, data2, n, [], n, Fs);
        end
        
        % Finds the data samples corresponding to the hammer impact
            % 'data' should be impact hammer data samples
            % 'windowTime' is the time in seconds you want the width of the impact to be
            % returns timestamps 'ts' and data points 'ds' corresponding to the hammer impact
        function [ts, ds] = findImpact(app, data, time, Fs, windowTime)
            % Find the impact profile and return the corresponding data
            [~, impactIndex] = findpeaks(data, "MinPeakHeight", std(data) * 10, "MinPeakDistance", round(0.01*Fs));
            windowWidth = round(windowTime * Fs / 2);
            
            % Check for multiple peaks
            if length(impactIndex) > 1
                uialert(app.UIFigure, 'Multiple Peaks Detected! Consider rejecting data', 'Multiple Peaks found')
            
            % Check for no peaks
            elseif isempty(impactIndex)
                uialert(app.UIFigure, 'Unable to find a peak, Consider rejecting data', 'No peak found') 
                ts = time;
                ds = data;
                return
            end
            
            % If impact happens less than 1 window Width from the start of the dataset
            if impactIndex(1) < windowWidth
                frame = 1:1:(impactIndex(end) + windowWidth);
                
            % if impact happens less than 1 window width from the end of the dataset
            elseif impactIndex(end) + windowWidth > length(time)
                frame = impactIndex(1):length(time);
            else
                frame = (impactIndex(1) - windowWidth):1:(impactIndex(end) + windowWidth);
            end
            ts = time(frame);
            ds = data(frame);
        end
        
        % Calculates the coherence between two channels of data (no windowing)
            % Agruments:
                % Pxx: Auto Spectral Density of one data channel
                % Pyy: Auto Spectral Density of the other data channel
                % Pxy: Cross Spectral Density between channels
            % Note: With only 1 data set (no averaging between successive runs, and no windowing),
            % the coherance between two channels of data will always be 1, no matter what the 
            % data actually is. Once you incorporate more data, this will no longer be the case. 
            % This phenomena has been cross confirmed by the use of Matlab's own built in coherance function,
            % mscohere, and by Peter Byrnes' Labview implementation of a similar script
        function coh = calcCoherance(app, Pxx, Pyy, Pxy)
            coh = abs(Pxy).^2 ./ (Pxx .* Pyy);
        end
        
        % Caluclates the frequency response between hammer and acceleration
        % data
        function [ys, fs] = calcFrequencyResponse(app, accelData, hammerData, Fs)            
            [accelPSD, fs] = calcSD(app, accelData, Fs);
            [hammerPSD, ~] = calcSD(app, hammerData, Fs);
            
            % Take sqrt to change units from kg^-2 to kg^-1
            ys = sqrt((accelPSD ./ hammerPSD));
        end
        
        % Looks for the channel(s) labeled with the load cell tag
        % returns logical array indexing into global variable 'Channels'
        function inds = getHammerChannels(app)
            global Channels;
            inds = zeros([1,length(Channels)]);
            for i = 1:length(Channels)
                inds(i) = Channels{i}.isLoadCell;
            end
            inds = logical(inds);
        end
        
        % Estimates Damping by applying an envelope filter to the magnitude
        % of acceleration, and fitting an exponential decay curve to that
        
        % returns b, the time constant of the exponential
        function b = estimateDampingCoef(app, data, time, impactTimestamps, plotOn)
            % Exclude the first 0.005s to exclude the hammer hit itself
            impactTime = time(time > (impactTimestamps(1) + 0.005));
            % Shift time so that t0 is the instant after the hammer hit
            impactTime = impactTime - impactTime(1);
            % Only look at the first 1.5 seconds of data after the hit
            impactTime = impactTime(impactTime < 1.5);
            
            % Select the corresonding data samples
            impactData = data(time > (impactTimestamps(1) + 0.005), :);
            impactData = impactData(1:length(impactTime), :);
            % Apply the envelope filter to the data
            [impactData, ~] = envelope(vecnorm(impactData, 2, 2), 150, 'peak');
            
            % Exponential function that we want to fit onto the data
            f = @(b,x) b(1).*exp(b(2).*x)+b(3);
            % Minimize the difference between the exponential curve and the
            % data
            B = fminsearch(@(b) norm(impactData - f(b,impactTime)), [0.3; -50; 0]);
            b = B(2);
            app.DampngConstantEditField.Value = b;
            
            % Makes a new figure, and plots the actual data (w/envelope)
            % and the exponential fit curve
            if plotOn
                figure;
                hold on
                xx = linspace(min(impactTime), max(impactTime), 500);
                plot(impactTime, impactData, 'r-')
                plot(xx, f(B, xx), 'b--')
            end
        end
        
        % Recursively disables a UI panel and all of it's children. Used to
        % lock acquisition settings
        function togglePanel(app, panel, status)
            elements = panel.Children;
            for i = 1:length(elements)
                element = elements(i);
                if isa(element, 'matlab.ui.container.PlotOptionsPanel')
                    togglePanel(app, element, status)
                else 
                    if isprop(element, 'Enable')
                       element.Enable = status;
                    end
                end
            end
        end
        
        % Takes cell arrays 'cummData' and 'cummTime', parses the raw data
        % in each cell, and averages all parsed data together. 
        % (using parseData())
        
        % Returns averagedData, a struct containing the averaged parsed
        % data
        function averagedData = averageParsedCumm(app, cummData, cummTime)
            averagedData = parseData(app, cummData{1}(:, getHammerChannels(app)), cummData{1}(:, ~getHammerChannels(app)), cummTime{1}, 0);
            dataFields = fieldnames(averagedData);
            
            % parse the remaining cells
            for i = 2:length(cummTime)
                averagedData = addToAverage(app, cummData{i}, cummTime{i}, averagedData, i-1); 
            end
        end
        
        % Takes an exsiting struct of averaged data, and adds the parsed
        % data from a single new dataset to the average
        function averagedData = addToAverage(app, newData, newTime, currAvgData, currNumAvgs)
            dataFields = fieldnames(currAvgData);
            % How much to weight exsisting data compared to new data
            ratio = currNumAvgs / (currNumAvgs + 1);
            averagedData = struct();
            res = parseData(app, newData(:, getHammerChannels(app)), newData(:, ~getHammerChannels(app)), newTime, 0);
            
            for i = 1:length(dataFields)
                field = dataFields{i};
                % only average fields that don't contain frequency
                % information. Implicitly relies on the fact that frequency
                % information between datasets should be the same because
                % the UI locks acqusition settings after the first dataset
                if ~contains(field, 'Fs')
                    currAvgData.(field) = currAvgData.(field) * ratio + res.(field) * (1 - ratio);
                end
            end
            averagedData = currAvgData;
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global s;
            global Channels;
            global testDateTime;
            global cummTime;
            global cummData;
            global cummDamping;
            global numAvgs;
            global avgData;
            
            % Clear/Initialize global variables
            cummTime = {};
            cummData = {};
            cummDamping = [];
            testDateTime = NaN;
            Channels = {};
            numAvgs = 0;
            avgData = struct();
            
            % Start DAQ session
            s = configureDAQ(app);
            
            togglePanel(app, app.PlotOptionsPanel, 'off');
            
            % Populate possible frequency settings
            freqs = 51200./(1:31);
            app.SamplingFreqHzDropDown.Items = cellstr(strsplit(num2str(freqs)));
        end

        % Button pushed function: AddChannelButton
        function AddChannelButtonPushed(app, event)
            % This function takes information specified in the Channel data
            % UI elements nad uses them to populate daq channels in s, as
            % well as in the global variable 'Channels'
            
            global Channels;
            global s;
            % Make sure DAQ is properly set up
            if ~checkDAQConfig(app, s)
                return
            end
            
            channel = struct();
            daqAttributes = struct();
            
            % Start populating DAQ Channel attributes/fields
            channel.name = app.ChannelnameEditField.Value;
            channel.deviceName = app.DevicetouseDropDown.Value;
            channel.ID = app.ChannelIDDropDown.Value;
            channel.measurementType = app.MeasurementTypeDropDown.Value;
            channel.isLoadCell = app.LoadcellImpactHammerCheckBox.Value;
            daqAttributes.Sensitivity = app.AccSensitivityVgEditField.Value;
            
            % If the input type is IEPE
            if strcmp(channel.measurementType, 'IEPE')
                app.ACcouplingCheckBox.Value = 1;
                daqAttributes.ExcitationCurrent = str2double(app.ExcitationcurrentAEditField.Value);
            elseif strcmp(channel.measurementType, 'Accelerometer')
                app.ACcouplingCheckBox.Value = 0;
                daqAttributes.ExcitationSource = 'None';
            end
            
            % Configure AC/DC Coupling
            if app.ACcouplingCheckBox.Value
                daqAttributes.Coupling = 'AC';
            else
                daqAttributes.Coupling = 'DC';
            end 
            
            % set daqAttributes as a struct field of the channel
            channel.daqAttributes = daqAttributes;
            
            % Create the string that identifies the channel in the UI
            displayName = strcat('Ch: ', channel.name, {' '}, channel.deviceName, {' '}, channel.ID, {' | '}, channel.daqAttributes.Coupling);
            if channel.isLoadCell
                displayName = strcat(displayName, ' | Load Cell');
            end
            channel.displayName = displayName{1};
            
            % Base information identifying the channel (device name and ID)
            channelConfig = strcat(channel.deviceName, channel.ID);
            
            % create a cell array containing channel config strings from
            % all existing channels
            allChannelNames = cellfun(@(channel) strcat(channel.deviceName, channel.ID), Channels, 'UniformOutput', false);
            if isempty(allChannelNames)
                app.ChannelsListBox.Items = {};
            end
            
            % Make sure the current channel the user is trying to add has a
            % unique device name and ID
            isUnique = sum(cellfun(@(name) strcmp(name, channelConfig), allChannelNames)) == 0;
            if ~isUnique
                uialert(app.UIFigure, 'Duplicate channel name/configuration. Please select a unique name/configuration', 'Duplicate Channel')
                return
            end
            
            % Add the channel to the DAQ session 's'
            populateDAQChannel(app, channel);
            
            % Add the channel to the global variable 'Channels'
            Channels{end+1} = channel;
            app.ChannelsListBox.Items{end + 1} = channel.displayName;
        end

        % Button pushed function: RemoveSelectedChannelsButton
        function RemoveSelectedChannelsButtonPushed(app, event)
            % This function allows the user to clear Channels. This purges
            % relevant channels from both the DAQ session 's', as well as
            % the global cell array 'Channels', and updates UI elements
            
            global Channels;
            global s;
            % Make sure DAQ session is configured
            if ~checkDAQConfig(app, s)
                return
            end
            
            % Find Channels that the user selected in the UI
            selectedChannelNames = app.ChannelsListBox.Value;
            channelNames = cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', 0);
            daqChannelNames = arrayfun(@(channel) channel.Name, s.Channels, 'UniformOutput', 0);
            DAQChannelsToRemove = zeros(size(s.Channels));
            remainingNames = ones(size(Channels));
            
            % Loop through the selected Channels, flag DAQ Channels indicies
            % with matching names for removal
            for i = 1:length(selectedChannelNames)
                targetName = selectedChannelNames{i};
                remainingNames = remainingNames - strcmp(channelNames, targetName);
                DAQChannelsToRemove = DAQChannelsToRemove + strcmp(daqChannelNames, targetName);
            end
            
            % Convert to logical array
            DAQChannelsToRemove = nonzeros(DAQChannelsToRemove .* (1:length(s.Channels)));
            % Removes channels from s
            removeChannel(s, DAQChannelsToRemove)
            
            
            % Reset the UI element displaying current Channels with up to
            % date channels, and update 'Channels'
            app.ChannelsListBox.Value = {};
            if numel(nonzeros(remainingNames)) > 0
                Channels = Channels(logical(remainingNames));
                app.ChannelsListBox.Items = channelNames(logical(remainingNames));
            else
                Channels = {};
                app.ChannelsListBox.Items = {};
            end
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            % Save the current configuration as a .mat file
            
            global Channels;
            global s;
            if ~checkDAQConfig(app, s)
                return
            end
            
            % Allows the user to specify the name and location
            [fileName, pathName] = uiputfile('*.mat','Save Configuration as:');
            save(fullfile(pathName, fileName), 'Channels');
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            % Takes saved configuration .mat file and properly loads
            % 'Channels' data and DAQ session 's' channels.
            
            global Channels;
            global s;
            uiopen('load');
            s = daq.createSession('ni');
            app.ChannelsListBox.Items = {};
            app.ChannelsListBox.Value = {};
            
            % Configure all of the DAQ Channels
            for i = 1:length(Channels)
               populateDAQChannel(app, Channels{i});
            end
            % Update UI elements to match current Channels
            app.ChannelsListBox.Items = cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', 0);
            
        end

        % Value changed function: UseTriggerCheckBox
        function UseTriggerCheckBoxValueChanged(app, event)
            % Toggles UI elements depending on user selection for
            % triggering
            
            value = app.UseTriggerCheckBox.Value;
            
            % If the user truns triggering on, enable corresponding UI
            % elements
            if value
                app.TriggersetpointVEditField.Enable = 'on';
                app.TriggerWindowsSpinner.Enable = 'on';
                app.TimeoutsEditField.Enable = 'on';

            % If the user truns triggering off, disable corresponding UI
            % elements
            else
                app.TriggersetpointVEditField.Enable = 'off';
                app.TriggerWindowsSpinner.Enable = 'off';
                app.TimeoutsEditField.Enable = 'off';
            end
        end

        % Value changed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
            % This function is in charge of starting data acqusition when
            % the user presses the button
            
            % Make sure DAQ is configured and that acqusition settings are
            % valid
            global s;
            if ~checkDAQConfig(app, s) || ~checkAcquisitionConfig(app, s)
                return;
            end
            
            % Disable UI button to prevent double presses
            app.AcquireDataButton.Enable = 'off';

            global t0;            
            global collectionComplete;
            global time;
            global data;
            global timeBuffer;
            global dataBuffer;
            global timeoutT0;
            global testDateTime;
            global Channels;
            global avgData;
            
            % initialize global variables
            collectionComplete = 0;
            time = [];
            data = [];
            
            % Set final DAQ settings for the session 's'
            s.Rate = str2double(app.SamplingFreqHzDropDown.Value);
            s.IsContinuous = 1;
            
            % Add the listener function app.collectData() as a callback for
            % 's' when there is available data to read
            lh = addlistener(s, 'DataAvailable', @app.collectData);
            
            % Initialize trigger buffers
            triggerWindow = app.TriggerWindowsSpinner.Value;
            dataBuffer = zeros([round(s.Rate * triggerWindow), length(s.Channels)]);
            timeBuffer = ones([round(s.Rate * triggerWindow), 1]) * -1;
            disp(size(timeBuffer));
            
            % If the date/time for the experiment has yet to be set, set it
            if ~isdatetime(testDateTime)
                testDateTime = datetime;
            end
            
            % Update based on trigger selection
            if app.UseTriggerCheckBox.Value
                app.AcquireDataButton.Text = 'Waiting for Trigger...';
                t0 = 0;
            
            % If no triggering is desired, start collecting data
            % immediately
            else
                app.AcquireDataButton.Text = 'Acquiring Data...';
                t0 = tic;
            end
            
            timeoutT0 = tic;
            
            % Start Acquisition!
            startBackground(s);
            
            % Clear plots
            cla(app.ImpactWaveformAxes);
            cla(app.ImpactPSDAxes);
            cla(app.FrequencyResponseAxes);
            cla(app.PSDAxes);
            cla(app.CoheranceAxes);
            
            % Wait until all the data is collected
            while ~collectionComplete
                pause(1)
                disp(s.IsRunning)
            end
            % Stop acquisition once collection is complete
            % ('collectionComplete' is updated in acquireData())
            stop(s);
            
            % Reset the button's text and props
            app.AcquireDataButton.Text = 'Acquire Data';
            app.AcquireDataButton.Value = 0;
            app.AcquireDataButton.Enable = 'on';
            
            % Play audio to signal end of data collection
            WarnWave = [sin(1:.6:400), sin(1:.7:400), sin(1:.4:400)];
            Audio = audioplayer(WarnWave, 22050);
            play(Audio);
            
            % Scale data by the sensitivities of each channel (now in units of g)
            data = data ./ cellfun(@(channel) channel.daqAttributes.Sensitivity, Channels);
            % Convert from g's to m/s^2
            data(:, ~getHammerChannels(app)) = data(:, ~getHammerChannels(app)) * 9.8;
            
            % Parse and plot data if there wasn't a triggering timeout
            if ~isempty(time)
                if isempty(avgData)
                    parseData(app, data(:, getHammerChannels(app)), data(:, ~getHammerChannels(app)), time, 1);
                else
                    % Pre-calculate the coherance average and plot that instead of just the single run coherance
                    res = parseData(app, data(:, getHammerChannels(app)), data(:, ~getHammerChannels(app)), time, 0);
                    global numAvgs
                    ratio = numAvgs / (1+numAvgs);
                    res.impactPSDMags = res.impactPSDMags / ratio + ratio * avgData.impactPSDMags;
                    res.PSDMags = res.PSDMags / ratio + ratio * avgData.PSDMags;
                    res.crossSD = res.crossSD / ratio + ratio * avgData.crossSD;
                    plotData(app, res, 0)
                end
            end

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Takes old .mat data and plots it, averaging if needed
            global time;
            global data;
            global cummTime;
            global cummData;
            global cummDamping;
            global Channels;
            global testDateTime;
            global avgData;
            global configurationData;
            global numAvgs;
            
            % Make sure the user doesn't unwittingly overwrite exsisting
            % cummulative data
            if ~isempty(cummTime)
                resp = uiconfirm(app.UIFigure, 'You currently have cummulative data cached. Loading new data will clear this data. Consider saving data before proceeding', 'Clearing Cummulative Data',...
                    'Options',{'Load new Data','Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
                if strcmp(resp, 'Cancel')
                    return
                end
            end
            
            % Make sure the user doesn't unwittingly overwrite exsisting
            % data
            if ~isempty(time)
                resp = uiconfirm(app.UIFigure, 'You currently have data cached. Loading new data will clear this exsisting data. Consider saving data before proceeding', 'Clearing Cummulative Data',...
                    'Options',{'Load new Data','Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
                if strcmp(resp, 'Cancel')
                    return
                end
            end
            
            % Reset UI
            app.NumberofTestsEditField.Value = 0;
            app.NumberofSamplesEditField.Value = 0;
            app.NumberofChannelsEditField.Value = 0;
            app.NotesTextArea.Value = "";
            app.TabGroup.Children(2).Title = 'Cummulative Acquisitions';
            
            % Reset global variabls
            cummTime = {};
            cummData = {};
            Channels = {};
            testDateTime = NaN;
            configurationData = struct();
           
            % clear plots
            cla(app.FrequencyResponseAxesCumm)
            cla(app.PSDAxesCumm)
            cla(app.CoheranceAxesCumm)
            cla(app.ImpactPSDAxesCumm)
            % disable UI panels related to acqusition settings
            togglePanel(app, app.AcquisitionSettingsPanel, 'off')
            togglePanel(app, app.ChannelSettingsPanel, 'off')
            
            % Switch to the cummulative data tab
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
            
            % Load the file
            [file, path] = uigetfile('*.mat');
            if isequal(file,0)
                return;
            else
                load(fullfile(path, file), "-mat", 'data', 'time', 'Channels', 'ChannelProps', 'cummData', 'cummTime', 'cummDamping', 'configurationData', 'avgData', 'notes');
            end
            
            togglePanel(app, app.PlotOptionsPanel, 'off')
            app.LoglogButton.Enable = 'off';
            app.SemilogxButton.Enable = 'off';
            app.SemilogyButton.Enable = 'off';
            app.LinearButton.Enable = 'off';
            
            % If data was collected before this app exsisted
            if exist('ChannelProps', 'var')
                % Try to find the hammer channel
                [~, hammerChannel] = min(abs(mad(data, 1) ./ median(data)));
                hammerInds = zeros([1,length(ChannelProps)]);
                hammerInds(hammerChannel) = 1;
                hammerInds = logical(hammerInds);
                % parse and plot the data
                parseData(app, data(:, hammerInds), data(:, ~hammerInds), time, 1);
            
            % If the data was collected using this app and is being
            % loaded again
            else
                % if the data was collected before crossSD was a part of
                % the parsed data store, recalculate the avg data
                if ~isfield(avgData, 'crossSD')
                    avgData = averageParsedCumm(app, cummData, cummTime);
                    numAvgs = length(cummData);
                end
                
                % Update channel list
                app.ChannelListListBox.Items = cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', false);
                configString = "";
                configCategories = fieldnames(configurationData);
                configs = struct2cell(configurationData);
                
                app.ChannelsListBox.Items = app.ChannelListListBox.Items;
                
                % build the string to display channel categories
                for i = 1:length(configCategories)
                    configString = sprintf('%s %s %s %s \n', configString, configCategories{i}, ':', configs{i});
                end
                app.ConfigurationdataTextArea.Value = configString;
                
                % populate notes text area UI element if possible
                if exist('notes', 'var')
                    app.NotesTextArea.Value = notes;
                end
                
                % update other UI elements with relevant info
                app.NumberofTestsEditField.Value = length(cummData);
                app.NumberofSamplesEditField.Value = sum(cellfun(@(channel) length(channel), cummData));
                app.NumberofChannelsEditField.Value = length(Channels);
                app.TabGroup.Children(2).Title = strcat('Cummulative Acquisitions (', num2str(length(cummData)), ')');
                
                % plot the avgData stored in the loaded file
                plotData(app, avgData, 0)
            end
            
            
            % empty time and data
            time = []; data = [];
            
            
        end

        % Value changed function: ChannelListListBox
        function ChannelListListBoxValueChanged(app, event)
            % This function updates the channel details UI element when the
            % user chooses a new channel to inspect
            global Channels;
            value = app.ChannelListListBox.Value;
            selectedChannelIndex = strcmp(cellfun(@(channel) channel.displayName, Channels, 'UniformOutput', false), value);
            channel = Channels{selectedChannelIndex};
            fields = fieldnames(channel);
            values = struct2cell(channel);
            string = '';
            
            % Construct the details string based on what channel is selected
            for line = 1:length(fields)
                if ischar(values{line}) || isstring(values{line})
                    string = sprintf('%s %s %s %s \n', string, fields{line}, ':', values{line});
                elseif islogical(values{line})
                    string = sprintf('%s %s %s %s \n', string, fields{line}, ':', num2str(values{line}));
                else
                    string = sprintf('%s %s %s \n', string, fields{line}, ':');
                    subfields = fieldnames(values{line});                    
                    subvalues = struct2cell(values{line});
                    for subline = 1:length(subfields)
                        string = sprintf('%s %s %s %s %s \n', string, char(9), subfields{subline}, ':', num2str(subvalues{subline}), newline);
                    end
                end
            end
            % Populate the details text area with the new string created
            app.ChannelDetailsTextArea.Value = string;
        end

        % Button pushed function: AddDatatoCummulativeButton
        function AddDatatoCummulativeButtonPushed(app, event)
            % This function takes the current data and timestamps stored in
            % global variables 'data' and 'time' (respectively), and adds
            % them to the cummulative data stores 'cummData' and 'cummTime'
            global time;
            global data;
            global Channels;
            global cummTime;
            global cummData;
            global cummDamping;
            global avgData;
            global numAvgs;
            
            % If there is actually data to add:
            if ~isempty(time)
                % If this is the first acqusition that the user is adding
                % to cummulative, give them a warning that doing so will
                % lock acuqisition settings
                if isempty(cummTime)
                    response = uiconfirm(app.UIFigure, 'Warning, proceeding with this action will lock all Acquisition Settings. The only way to reset them is to clear the cummulative data. Please make sure that all settings are what you want them to be', 'Locking Acquisition Settings');
                    if ~strcmp(response, 'OK')
                        return
                    end
                end
                
                % If this is the first entry the user is adding
                if isempty(cummTime)
                    % simply parse the data and set that as the avg
                    avgData = parseData(app, data(:, getHammerChannels(app)), data(:, ~getHammerChannels(app)), time, 0);
                    numAvgs = 1;
                
                % if there is exsisting data, add the new data to avg
                else
                    avgData = addToAverage(app, data, time, avgData, numAvgs);
                    numAvgs = numAvgs + 1;
                end
                % Add the new data cummlative data stores
                cummTime{end+1} = time;
                cummData{end+1} = data;
                cummDamping(end+1) = app.DampngConstantEditField.Value;
                
                % clear data and time
                time = [];
                data = [];
                
                % update UI fields to reflect new cummulative data
                app.NumberofTestsEditField.Value = app.NumberofTestsEditField.Value + 1;
                app.NumberofSamplesEditField.Value = app.NumberofSamplesEditField.Value + length(time);
                if app.NumberofChannelsEditField.Value == 0
                    app.NumberofChannelsEditField.Value = length(Channels);
                end
                
                % Update the Cummulative data tab to reflect the number of
                % data entries
                cummTabName = app.TabGroup.Children(2).Title;
                if strcmp(cummTabName, 'Cummulative Acquisitions')
                    app.TabGroup.Children(2).Title = strcat(cummTabName, ' (', num2str(app.NumberofTestsEditField.Value), ')');    
                else
                    app.TabGroup.Children(2).Title = strcat(cummTabName(1:end-4), ' (', num2str(app.NumberofTestsEditField.Value), ')'); 
                end
                
                % Clear plots in the current acquisition tab
                cla(app.ImpactWaveformAxes);
                cla(app.ImpactPSDAxes);
                cla(app.FrequencyResponseAxes);
                cla(app.PSDAxes);
                cla(app.CoheranceAxes);
                
                % Disable daq settings pannels to lock collection settings
                togglePanel(app, app.AcquisitionSettingsPanel, 'off')
                togglePanel(app, app.ChannelSettingsPanel, 'off')
                
            % If there is no exsisting data, warn the user
            else
                uialert(app.UIFigure, 'No data to add! Collect Data first.', 'No data')
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % This function simply updates a few UI elements when the user
            % changes tabs
            global cummTime;
            global cummDamping;
            global testDateTime;
            global avgData;
            selectedTab = app.TabGroup.SelectedTab;
            
            if strcmp(selectedTab.Title, app.TabGroup.Children(2).Title)
                app.ChannelListListBox.Items = app.ChannelsListBox.Items;
                if ~strcmp(app.TestarticleEditField.Value, '')
                    string = sprintf('%s %s %s \n', app.TestarticleEditFieldLabel.Text, ':', app.TestarticleEditField.Value);
                    string = sprintf('%s %s %s %s \n', string, app.TestEngineerEditFieldLabel.Text, ':', app.TestEngineerEditField.Value);
                end
                
                if isdatetime(testDateTime)
                    string = sprintf('%s %s %s', string, 'Date and Time:', datestr(testDateTime));
                end
                app.ConfigurationdataTextArea.Value = string;
                
                if ~isempty(cummTime)
                    
                    plotData(app, avgData, 0)
                    app.AverageDampingConstEditField.Value = mean(cummDamping);
                end
                
            end
        end

        % Button pushed function: ClearcummulativedataButton
        function ClearcummulativedataButtonPushed(app, event)
            % Clears the stored cummulative data and resets the UI for new
            % data acqusition rounds
        
            global cummTime;
            global cummData;
            global testDateTime;
           
            % Make sure the user really wants to clear the data
            response = uiconfirm(app.UIFigure, 'Are you sure you want to clear all cummulative data?', 'Clear Data', 'Icon', 'warning');
            if strcmp(response, 'OK')
                % Clear UI fields
                app.NumberofTestsEditField.Value = 0;
                app.NumberofSamplesEditField.Value = 0;
                app.NumberofChannelsEditField.Value = 0;
                app.TabGroup.Children(2).Title = 'Cummulative Acquisitions';
                
                % Clear global variables
                cummTime = {};
                cummData = {};
                testDateTime = NaN;
                
                % Clear plots
                cla(app.FrequencyResponseAxesCumm)
                cla(app.PSDAxesCumm)
                cla(app.CoheranceAxesCumm)
                cla(app.ImpactPSDAxesCumm)
                
                % turn collecting settings UI panels on
                togglePanel(app, app.AcquisitionSettingsPanel, 'on')
                togglePanel(app, app.ChannelSettingsPanel, 'on')
                if ~app.UseTriggerCheckBox.Value
                    app.TriggerWindowsSpinner.Enable = 'off';
                    app.TriggersetpointVEditField.Enable = 'off';
                    app.TimeoutsEditField.Enable = 'off';
                end
                
            end
            
            togglePanel(app, app.PlotOptionsPanel, 'off')
            app.LoglogButton.Enable = 'off';
            app.SemilogxButton.Enable = 'off';
            app.SemilogyButton.Enable = 'off';
            app.LinearButton.Enable = 'off';
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            global cummTime;
            global cummData;
            global cummDamping;
            global Channels;
            global testDateTime;
            global avgData;
            global configurationData;
            
            % Create configuration data struct to save
            if isa(configurationData, 'double')
                configurationData = struct();
                configurationData.('testArticle') = app.TestarticleEditField.Value;
                configurationData.('testEngineer') = app.TestEngineerEditField.Value;
                configurationData.('testDateTime') = testDateTime;
            end
            notes = app.NotesTextArea.Value;
            
            [fileName, pathName] = uiputfile('*.mat','Save Data and Configuration as:');
            save(fullfile(pathName, fileName), 'cummTime', 'cummData', 'cummDamping', 'avgData', 'Channels', 'configurationData', 'notes');
        end

        % Button pushed function: ScanForDevicesButton
        function ScanForDevicesButtonPushed(app, event)
            % Tries to configure the DAQ session if it hasn't been already
            % This button turns off once the session is set up
            global s;
            s = configureDAQ(app);
        end

        % Button pushed function: InteractwithDataButton
        function InteractwithDataButtonPushed(app, event)
            % Creates a standalone figure and prompts the user to save the
            % figure
            global avgData;
            % Make sure there is data to save
            if ~isempty(avgData)
                % plot the data
                f = plotData(app, avgData, 1);
%                 % Save the data
%                 [FileName, PathName] = uiputfile('*.fig','Save Entire Figure As...');
%                 hgsave(fullfile(PathName,FileName));
%                 close(f);
            else
                uialert(app.UIFigure, 'No data to add! Collect Data first.', 'No data')
            end
        end

        % Value changed function: LabViewModeCheckBox
        function LabViewModeCheckBoxValueChanged(app, event)
            % Changes the background to forest green for Peter
            
            value = app.LabViewModeCheckBox.Value;
            if value
                for i = 1:length(app.TabGroup.Children)
                    app.TabGroup.Children(i).BackgroundColor = [37/256, 121/256, 88/256];
                    app.UIFigure.Color = [37/256, 121/256, 88/256];
                end
            else
                for i = 1:length(app.TabGroup.Children)
                    app.TabGroup.Children(i).BackgroundColor = [0.94,0.94,0.94];
                    app.UIFigure.Color = [0.94,0.94,0.94];
                end
            end
        end

        % Button pushed function: PlottimedomaindataButton
        function PlottimedomaindataButtonPushed(app, event)
            % Creates a new figure and plots time domain data
            figure;
            global data;
            global time;
            
            subplot(2,1,1)
            d = data(:, ~getHammerChannels(app));
            plot(time, d);
            title('Time domain accelerations')
            xlabel('time (s)')
            accelLegend = channelLegend(cellfun(@(channel) ~channel.isLoadCell, Channels));
            legend(accelLegend)
            subplot(2,1,2)
            plot(time, data(:, getHammerChannels(app)));
            title('Time domain hammer impulse')
            xlabel('time (s)')
            
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            % This is a filler function. I really just wanted a button I
            % could press that would call a function I could use to run
            % tests. 
            global cummData;
            global cummTime;
            global avgData;
            a = cummData{1};
            n = length(a);
            
            FF = round(1/mean(diff(cummTime{1})));
            w = 3 * FF;
            data = a(:, ~getHammerChannels(app));
            hammer = a(:, getHammerChannels(app));
            [dataPSDs, fsd] = pwelch(data, w, [], n, FF);
            [hammerPSDs, fshammer] = pwelch(hammer, w, [], n, FF);
            [xcs, ~]  = cpsd(data, hammer, w, [], n, FF);

            for i = 2:length(cummData)
                aa = cummData{i};
                data = aa(:, ~getHammerChannels(app));
                hammer = aa(:, getHammerChannels(app));
                [dataPSD, ~] = pwelch(data, w, [], n, FF);
                [hammerPSD, ~] = pwelch(hammer, w, [], n, FF);
                [xc, ~]  = cpsd(data, hammer, w, [], n, FF);
                dataPSDs = dataPSDs + dataPSD;
                hammerPSDs = hammerPSDs + hammerPSD;
                xcs = xc + xcs;
            end
            xcs = xcs / length(cummData);
            dataPSDs = dataPSDs / length(cummData);
            hammerPSDs = hammerPSDs / length(cummData);
            coh = abs(xcs).^2 ./ (dataPSDs .* hammerPSDs);
            figure
            semilogx(fsd, coh)
            disp('don')
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            global avgData
            plotData(app, avgData, 0)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LatestacquisitionTab = uitab(app.TabGroup);
            app.LatestacquisitionTab.Title = 'Latest acquisition';
            app.ImpactWaveformAxes = uiaxes(app.LatestacquisitionTab);
            title(app.ImpactWaveformAxes, 'Impact Waveform')
            xlabel(app.ImpactWaveformAxes, 't (s)')
            ylabel(app.ImpactWaveformAxes, 'mag (V)')
            app.ImpactPSDAxes = uiaxes(app.LatestacquisitionTab);
            title(app.ImpactPSDAxes, 'Impact PSD')
            xlabel(app.ImpactPSDAxes, 'f (Hz)')
            ylabel(app.ImpactPSDAxes, 'mag')
            app.PSDAxes = uiaxes(app.LatestacquisitionTab);
            title(app.PSDAxes, 'PSD')
            xlabel(app.PSDAxes, 'f (Hz)')
            ylabel(app.PSDAxes, '(m/s^2)^2 / Hz')
            app.CoheranceAxes = uiaxes(app.LatestacquisitionTab);
            title(app.CoheranceAxes, 'Coherance')
            xlabel(app.CoheranceAxes, 'f (Hz)')
            ylabel(app.CoheranceAxes, 'Cohereance')
            app.FrequencyResponseAxes = uiaxes(app.LatestacquisitionTab);
            title(app.FrequencyResponseAxes, 'Frequency Response (1/m)')
            xlabel(app.FrequencyResponseAxes, 'f (Hz)')
            ylabel(app.FrequencyResponseAxes, '1/m (1/kg)')
            app.ChannelSettingsPanel = uipanel(app.LatestacquisitionTab);
            app.ChannelSettingsPanel.Title = 'Channel Settings';
            app.ChannelnameEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.ChannelnameEditFieldLabel.Text = 'Channel name';
            app.ChannelnameEditField = uieditfield(app.ChannelSettingsPanel, 'text');
            app.DevicetouseDropDown = uidropdown(app.ChannelSettingsPanel);
            app.DevicetouseDropDown.Items = {'Device to use', ' bobby', ''};
            app.DevicetouseDropDown.Value = 'Device to use';
            app.MeasurementTypeDropDown = uidropdown(app.ChannelSettingsPanel);
            app.MeasurementTypeDropDown.Items = {'Voltage'};
            app.MeasurementTypeDropDown.Value = 'Voltage';
            app.ACcouplingCheckBox = uicheckbox(app.ChannelSettingsPanel);
            app.ACcouplingCheckBox.Text = 'AC coupling';
            app.ChannelIDDropDown = uidropdown(app.ChannelSettingsPanel);
            app.ChannelIDDropDown.Items = {'Channel ID'};
            app.ChannelIDDropDown.Value = 'Channel ID';
            app.ExcitationcurrentAEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.ExcitationcurrentAEditFieldLabel.Text = 'Excitation current (A)';
            app.ExcitationcurrentAEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.ExcitationcurrentAEditField.Limits = [0.002 0.01];
            app.ExcitationcurrentAEditField.Value = 0.002;
            app.InternalSensitivityEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.InternalSensitivityEditFieldLabel.Text = 'Internal Sensitivity';
            app.InternalSensitivityEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.InternalSensitivityEditField.Limits = [0 Inf];
            app.AccSensitivityVgEditFieldLabel = uilabel(app.ChannelSettingsPanel);
            app.AccSensitivityVgEditFieldLabel.Text = 'Acc. Sensitivity (V/g)';
            app.AccSensitivityVgEditField = uieditfield(app.ChannelSettingsPanel, 'numeric');
            app.AccSensitivityVgEditField.Limits = [0 1000];
            app.AddChannelButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannelButton.Text = 'Add Channel';
            app.SaveConfigButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ChannelsListBoxLabel = uilabel(app.ChannelSettingsPanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.ChannelSettingsPanel);
            app.ChannelsListBox.Items = {'No channels'};
            app.ChannelsListBox.Multiselect = 'on';
            app.ChannelsListBox.Value = {'No channels'};
            app.RemoveSelectedChannelsButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.RemoveSelectedChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedChannelsButtonPushed, true);
            app.RemoveSelectedChannelsButton.Text = 'Remove Selected Channels';
            app.LoadcellImpactHammerCheckBox = uicheckbox(app.ChannelSettingsPanel);
            app.LoadcellImpactHammerCheckBox.Text = 'Load cell (Impact Hammer)';
            app.ScanForDevicesButton = uibutton(app.ChannelSettingsPanel, 'push');
            app.ScanForDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanForDevicesButtonPushed, true);
            app.ScanForDevicesButton.Text = 'Scan For Devices';
            app.AcquisitionSettingsPanel = uipanel(app.LatestacquisitionTab);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.TriggerSettingsPanel = uipanel(app.AcquisitionSettingsPanel);
            app.TriggerSettingsPanel.Title = 'Trigger Settings';
            app.UseTriggerCheckBox = uicheckbox(app.TriggerSettingsPanel);
            app.UseTriggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTriggerCheckBoxValueChanged, true);
            app.UseTriggerCheckBox.Text = 'Use Trigger';
            app.TriggersetpointVEditFieldLabel = uilabel(app.TriggerSettingsPanel);
            app.TriggersetpointVEditFieldLabel.Text = 'Trigger setpoint (V)';
            app.TriggersetpointVEditField = uieditfield(app.TriggerSettingsPanel, 'numeric');
            app.TriggersetpointVEditField.Limits = [0 1];
            app.TriggersetpointVEditField.Enable = 'off';
            app.TriggersetpointVEditField.Value = 0.007;
            app.TriggerWindowsSpinnerLabel = uilabel(app.TriggerSettingsPanel);
            app.TriggerWindowsSpinnerLabel.Text = 'Trigger Window (s)';
            app.TriggerWindowsSpinner = uispinner(app.TriggerSettingsPanel);
            app.TriggerWindowsSpinner.Step = 0.01;
            app.TriggerWindowsSpinner.Limits = [0 5];
            app.TriggerWindowsSpinner.Enable = 'off';
            app.TriggerWindowsSpinner.Value = 0.01;
            app.TimeoutsEditFieldLabel = uilabel(app.TriggerSettingsPanel);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.TriggerSettingsPanel, 'numeric');
            app.TimeoutsEditField.Limits = [1 120];
            app.TimeoutsEditField.Enable = 'off';
            app.TimeoutsEditField.Value = 15;
            app.CollectionTimesEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.CollectionTimesEditFieldLabel.Text = 'Collection Time (s)';
            app.CollectionTimesEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.CollectionTimesEditField.Limits = [0 1000];
            app.CollectionTimesEditField.Value = 5;
            app.SamplingFreqHzDropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SamplingFreqHzDropDownLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzDropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.SamplingFreqHzDropDown.Items = {'51200'};
            app.SamplingFreqHzDropDown.Value = '51200';
            app.SystemmasskgEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SystemmasskgEditFieldLabel.Text = 'System mass (kg)';
            app.SystemmasskgEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.SystemmasskgEditField.Limits = [0 100];
            app.SystemmasskgEditField.Value = 1;
            app.ConfigurationDetailsPanel = uipanel(app.LatestacquisitionTab);
            app.ConfigurationDetailsPanel.Title = 'Configuration Details';
            app.TestarticleEditFieldLabel = uilabel(app.ConfigurationDetailsPanel);
            app.TestarticleEditFieldLabel.Text = 'Test article';
            app.TestarticleEditField = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.TestEngineerEditFieldLabel = uilabel(app.ConfigurationDetailsPanel);
            app.TestEngineerEditFieldLabel.Text = 'Test Engineer';
            app.TestEngineerEditField = uieditfield(app.ConfigurationDetailsPanel, 'text');
            app.AcquireDataButton = uibutton(app.LatestacquisitionTab, 'state');
            app.AcquireDataButton.ValueChangedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
            app.LoadDataButton = uibutton(app.LatestacquisitionTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.AddDatatoCummulativeButton = uibutton(app.LatestacquisitionTab, 'push');
            app.AddDatatoCummulativeButton.ButtonPushedFcn = createCallbackFcn(app, @AddDatatoCummulativeButtonPushed, true);
            app.AddDatatoCummulativeButton.Text = 'Add Data to Cummulative';
            app.DampngConstantEditFieldLabel = uilabel(app.LatestacquisitionTab);
            app.DampngConstantEditFieldLabel.Text = 'Dampng Constant';
            app.DampngConstantEditField = uieditfield(app.LatestacquisitionTab, 'numeric');
            app.DampngConstantEditField.Editable = 'off';
            app.CummulativeAcquisitionsTab = uitab(app.TabGroup);
            app.CummulativeAcquisitionsTab.Title = 'Cummulative Acquisitions';
            app.ChannelInfoPanel = uipanel(app.CummulativeAcquisitionsTab);
            app.ChannelInfoPanel.Title = 'Channel Info';
            app.ChannelListListBoxLabel = uilabel(app.ChannelInfoPanel);
            app.ChannelListListBoxLabel.Text = 'Channel List';
            app.ChannelListListBox = uilistbox(app.ChannelInfoPanel);
            app.ChannelListListBox.Items = {'No channels'};
            app.ChannelListListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListListBoxValueChanged, true);
            app.ChannelListListBox.Value = 'No channels';
            app.ChannelDetailsTextAreaLabel = uilabel(app.ChannelInfoPanel);
            app.ChannelDetailsTextAreaLabel.Text = 'Channel Details';
            app.ChannelDetailsTextArea = uitextarea(app.ChannelInfoPanel);
            app.ChannelDetailsTextArea.Editable = 'off';
            app.ConfigurationInfoPanel = uipanel(app.CummulativeAcquisitionsTab);
            app.ConfigurationInfoPanel.Title = 'Configuration Info';
            app.NotesTextAreaLabel = uilabel(app.ConfigurationInfoPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.ConfigurationInfoPanel);
            app.ConfigurationdataTextAreaLabel = uilabel(app.ConfigurationInfoPanel);
            app.ConfigurationdataTextAreaLabel.Text = 'Configuration data';
            app.ConfigurationdataTextArea = uitextarea(app.ConfigurationInfoPanel);
            app.ConfigurationdataTextArea.Editable = 'off';
            app.DataInfoPanel = uipanel(app.CummulativeAcquisitionsTab);
            app.DataInfoPanel.Title = 'Data Info';
            app.SaveDataButton = uibutton(app.DataInfoPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.NumberofSamplesEditFieldLabel = uilabel(app.DataInfoPanel);
            app.NumberofSamplesEditFieldLabel.Text = 'Number of Samples';
            app.NumberofSamplesEditField = uieditfield(app.DataInfoPanel, 'numeric');
            app.NumberofSamplesEditField.Limits = [0 Inf];
            app.NumberofSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.NumberofSamplesEditField.Editable = 'off';
            app.NumberofTestsEditFieldLabel = uilabel(app.DataInfoPanel);
            app.NumberofTestsEditFieldLabel.Text = 'Number of Tests';
            app.NumberofTestsEditField = uieditfield(app.DataInfoPanel, 'numeric');
            app.NumberofTestsEditField.Limits = [0 Inf];
            app.NumberofTestsEditField.Editable = 'off';
            app.NumberofChannelsEditFieldLabel = uilabel(app.DataInfoPanel);
            app.NumberofChannelsEditFieldLabel.Text = 'Number of Channels';
            app.NumberofChannelsEditField = uieditfield(app.DataInfoPanel, 'numeric');
            app.NumberofChannelsEditField.Limits = [0 Inf];
            app.NumberofChannelsEditField.Editable = 'off';
            app.ClearcummulativedataButton = uibutton(app.DataInfoPanel, 'push');
            app.ClearcummulativedataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcummulativedataButtonPushed, true);
            app.ClearcummulativedataButton.Text = 'Clear cummulative data';
            app.InteractwithDataButton = uibutton(app.DataInfoPanel, 'push');
            app.InteractwithDataButton.ButtonPushedFcn = createCallbackFcn(app, @InteractwithDataButtonPushed, true);
            app.InteractwithDataButton.Text = 'Interact with Data';
            app.LabViewModeCheckBox = uicheckbox(app.DataInfoPanel);
            app.LabViewModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabViewModeCheckBoxValueChanged, true);
            app.LabViewModeCheckBox.Text = 'LabView Mode';
            app.PSDAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.PSDAxesCumm, 'Averaged Accel PSD')
            xlabel(app.PSDAxesCumm, 'Frequency (Hz)')
            ylabel(app.PSDAxesCumm, '(m/s^2)^2 / Hz')
            app.ImpactPSDAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.ImpactPSDAxesCumm, 'Averaged Impact PSD')
            xlabel(app.ImpactPSDAxesCumm, 'Frequency (Hz)')
            ylabel(app.ImpactPSDAxesCumm, 'N^2 / Hz')
            app.FrequencyResponseAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.FrequencyResponseAxesCumm, 'Averaged Frequency Response')
            xlabel(app.FrequencyResponseAxesCumm, 'Frequency (Hz)')
            ylabel(app.FrequencyResponseAxesCumm, 'Inverse Mass (1/kg)')
            app.CoheranceAxesCumm = uiaxes(app.CummulativeAcquisitionsTab);
            title(app.CoheranceAxesCumm, 'Averaged Coherance')
            xlabel(app.CoheranceAxesCumm, 'Frequency (Hz')
            ylabel(app.CoheranceAxesCumm, 'Coherance (unitless)')
            app.AverageDampingConstEditFieldLabel = uilabel(app.CummulativeAcquisitionsTab);
            app.AverageDampingConstEditFieldLabel.Text = 'Average Damping Const';
            app.AverageDampingConstEditField = uieditfield(app.CummulativeAcquisitionsTab, 'numeric');
            app.AverageDampingConstEditField.Editable = 'off';
            app.DebugButton = uibutton(app.UIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.PlottimedomaindataButton = uibutton(app.UIFigure, 'push');
            app.PlottimedomaindataButton.ButtonPushedFcn = createCallbackFcn(app, @PlottimedomaindataButtonPushed, true);
            app.PlottimedomaindataButton.Text = 'Plot time domain data';
            app.PlotOptionsPanel = uipanel(app.UIFigure);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.xmaxEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.xminEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.AxisScalingButtonGroup = uibuttongroup(app.PlotOptionsPanel);
            app.AxisScalingButtonGroup.Title = 'Axis Scaling';
            app.LoglogButton = uiradiobutton(app.AxisScalingButtonGroup);
            app.LoglogButton.Enable = 'off';
            app.LoglogButton.Text = 'Log-log';
            app.LoglogButton.Value = true;
            app.SemilogxButton = uiradiobutton(app.AxisScalingButtonGroup);
            app.SemilogxButton.Enable = 'off';
            app.SemilogxButton.Text = 'Semilog-x';
            app.SemilogyButton = uiradiobutton(app.AxisScalingButtonGroup);
            app.SemilogyButton.Enable = 'off';
            app.SemilogyButton.Text = 'Semilog-y';
            app.LinearButton = uiradiobutton(app.AxisScalingButtonGroup);
            app.LinearButton.Enable = 'off';
            app.LinearButton.Text = 'Linear';
            app.ReplotButton = uibutton(app.PlotOptionsPanel, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.ImpactPeakMaxNLabel = uilabel(app.UIFigure);
            app.ImpactPeakMaxNLabel.Text = {'Impact  Peak'; ' Max (N)'};
            app.ImpactPeakMaxNEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImpactPeakMaxNEditField.Limits = [0 Inf];
            app.ImpactPeakMaxNEditField.Value = 3000;
            app.ImpactPeakMinNEditFieldLabel = uilabel(app.UIFigure);
            app.ImpactPeakMinNEditFieldLabel.Text = {'Impact Peak'; ' Min (N)'};
            app.ImpactPeakMinNEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImpactPeakMinNEditField.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = hammer_test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end